classdef piano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        C1Button     matlab.ui.control.StateButton
        D1Button     matlab.ui.control.StateButton
        E1Button     matlab.ui.control.StateButton
        F1Button     matlab.ui.control.StateButton
        G1Button     matlab.ui.control.StateButton
        A1Button     matlab.ui.control.StateButton
        B1Button     matlab.ui.control.StateButton
        Cb1Button    matlab.ui.control.StateButton
        Db1Button    matlab.ui.control.StateButton
        Fb1Button    matlab.ui.control.StateButton
        Gb1Button    matlab.ui.control.StateButton
        Ab1Button    matlab.ui.control.StateButton
        C2Button     matlab.ui.control.StateButton
        D2Button     matlab.ui.control.StateButton
        E2Button     matlab.ui.control.StateButton
        F2Button     matlab.ui.control.StateButton
        G2Button     matlab.ui.control.StateButton
        A2Button     matlab.ui.control.StateButton
        B2Button     matlab.ui.control.StateButton
        Cb2Button    matlab.ui.control.StateButton
        Db2Button    matlab.ui.control.StateButton
        Fb2Button    matlab.ui.control.StateButton
        Gb2Button    matlab.ui.control.StateButton
        Ab2Button    matlab.ui.control.StateButton
        Label        matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        QULabel      matlab.ui.control.Label
        Ctrl16Label  matlab.ui.control.Label
        AMLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        % 数据记录键值表
        map = containers.Map(["control","a","b","c"],["release","","",""]);
    end
    
    methods (Access = private)
        
        %元素逐乘，实现包络
        function result = envelope(app,sound)
            result = sound .* exp(-(0:length(sound)-1)/(length(sound)/4));
        end
        
        %弹奏函数 a是音名 b表示第几个八度 c表示提升多少个八度 d表示多少个八分音符
        function result=PSound(app,a,b,c,d)
            %半音
            K = 2^(1/12);
            %采样率
            Fs=8000;
            %钢琴的C1是32.70 hz
            fc1=32.70;
            fc0=fc1/2;
            names=["c","cb","d","db","e","f","fb","g","gb","a","ab","b"];
            for i = 1:length(names)
                if strcmp(names(i), a)
                    base=(2^str2double(c))*(2^str2double(b))*(K^(i-1));
                    freq=fc0*base; %计算得到实际频率
                    
                    %timelist是真实钢琴每个八度内C键的播放时长，两个为一组
                    timelist=[1.099 3.004 4.452 6.326 9.454 10.941 12.578 14.055 16.494 17.924 20.092 20.901 24.441 25.025];
                    
                    %计算真实钢琴每个八度内单个按键的发音时长 between
                    between=(timelist(str2double(b)*2)-timelist(str2double(b)*2-1))*1000;
                    
                    time=d*between/2; % /2表示单个八分音符的时长
                    
                    disp(time);
                    t = 0:1/Fs:time/1000; % 时间向量，ms
                    disp(freq);
                    % 基波+谐波 钢琴的分析在piano_analyze.m
                    y = 1*sin(2*pi*freq*t); % 基波
                    ss=[];
                    switch b
                    case '1'
                        ss=8*[0.6631    1.4440    0.3990    0.4396    0.4717    0.1417    0.0102    0.2587    0.2650    0.1286];
                    case '2'
                        ss=[3.5937    1.0734    1.1605    0.1479    0.4091    0.6744    0.0265    0.5220    0.7499    0.5661];
                    case '3'
                        ss=[0.2252    0.6139    0.2573    0.1904    0.0968    0.2149    0.0508    0.0644    0.0638    0.0205];
                    case '4'
                        ss=[0.7394    0.1636    0.0881    0.0907    0.0923    0.1598    0.0810    0.1041    0.0295    0.0235];
                    case '5'
                        ss=[0.2313    0.1567    0.1337    0.0989    0.0646    0.0273    0.0196    0.0023    0.0037    0.0007];
                    case '6'
                        ss=[0.3828    0.1225    0.0360    0.0179    0.0064    0.0028];
                    case '7'
                        ss=[0.0535    0.0065];
                    end
                    
                    for index = 1:length(ss)
                        y = y+ss(index)*sin(2*pi*freq*(index+1)*t);
                    end

                    result=y;
                    return;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: A1Button, A2Button, Ab1Button, 
        % Ab2Button, B1Button, B2Button, C1Button, C2Button, 
        % Cb1Button, Cb2Button, D1Button, D2Button, Db1Button, 
        % Db2Button, E1Button, E2Button, F1Button, F2Button, 
        % Fb1Button, Fb2Button, G1Button, G2Button, Gb1Button, 
        % Gb2Button
        function ButtonValueChanged(app, event)
            if event.Source.Value == true
                str=lower(event.Source.Text);
                disp(str);
                y=app.PSound(str(1:end-1),str(end),"0",app.Slider.Value);
                sound(app.envelope(y),8000);
                event.Source.Value = false;
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if key=="control"
                app.map("control")="press";
                return;
            end
            
            if key>="1" && key<="6" && app.map("control")=="press"
                i=str2double(key);
                app.C1Button.Text="C"+num2str(i);
                app.Cb1Button.Text="Cb"+num2str(i);
                app.D1Button.Text="D"+num2str(i);
                app.Db1Button.Text="Db"+num2str(i);
                app.E1Button.Text="E"+num2str(i);
                app.F1Button.Text="F"+num2str(i);
                app.Fb1Button.Text="Fb"+num2str(i);
                app.G1Button.Text="G"+num2str(i);
                app.Gb1Button.Text="Gb"+num2str(i);
                app.A1Button.Text="A"+num2str(i);
                app.Ab1Button.Text="Ab"+num2str(i);
                app.B1Button.Text="B"+num2str(i);
                
                i=i+1;
                app.C2Button.Text="C"+num2str(i);
                app.Cb2Button.Text="Cb"+num2str(i);
                app.D2Button.Text="D"+num2str(i);
                app.Db2Button.Text="Db"+num2str(i);
                app.E2Button.Text="E"+num2str(i);
                app.F2Button.Text="F"+num2str(i);
                app.Fb2Button.Text="Fb"+num2str(i);
                app.G2Button.Text="G"+num2str(i);
                app.Gb2Button.Text="Gb"+num2str(i);
                app.A2Button.Text="A"+num2str(i);
                app.Ab2Button.Text="Ab"+num2str(i);
                app.B2Button.Text="B"+num2str(i);
            else
                b="";
                switch key
                    case '1'
                        b=app.C1Button;
                    case '2'
                        b=app.D1Button;
                    case '3'
                        b=app.E1Button;
                    case '4'
                        b=app.F1Button;
                    case '5'
                        b=app.G1Button;
                    case '6'
                        b=app.A1Button;
                    case '7'
                        b=app.B1Button;
                    case 'q'
                        b=app.C2Button;
                    case 'w'
                        b=app.D2Button;
                    case 'e'
                        b=app.E2Button;
                    case 'r'
                        b=app.F2Button;
                    case 't'
                        b=app.G2Button;
                    case 'y'
                        b=app.A2Button;
                    case 'u'
                        b=app.B2Button;
                    otherwise
                        b="";
                end
                if b~=""
                    str=lower(b.Text);
                    if isKey(app.map,key)==false || strcmp(app.map(key),"")
                        disp(str);
                        % 模拟按钮按下动画
                        set(b, 'BackgroundColor', [0.8 0.8 0.8]);
                        app.map(key)="press";
                        y=app.PSound(str(1:end-1),str(end),"0",app.Slider.Value);
                        sound(app.envelope(y),8000);
                    end
                else
                    switch key
                        case 'a'
                            app.Slider.Value=1;
                        case 's'
                            app.Slider.Value=2;
                        case 'd'
                            app.Slider.Value=3;
                        case 'f'
                            app.Slider.Value=4;
                        case 'g'
                            app.Slider.Value=5;
                        case 'h'
                            app.Slider.Value=6;
                        case 'j'
                            app.Slider.Value=7;
                        case 'k'
                            app.Slider.Value=8;
                        case 'l'
                            app.Slider.Value=9;
                        case 'z'
                            app.Slider.Value=10;
                        case 'x'
                            app.Slider.Value=11;
                        case 'c'
                            app.Slider.Value=12;
                        case 'v'
                            app.Slider.Value=13;
                        case 'b'
                            app.Slider.Value=14;
                        case 'n'
                            app.Slider.Value=15;
                        case 'm'
                            app.Slider.Value=16;
                    end
                end
                
            end
            
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            
            if key=="control"
                app.map("control")="release";
                return;
            end
            
            if isKey(app.map,key)
                app.map(key)="";
            end
            
            b="";
            switch key
                case '1'
                    b=app.C1Button;
                case '2'
                    b=app.D1Button;
                case '3'
                    b=app.E1Button;
                case '4'
                    b=app.F1Button;
                case '5'
                    b=app.G1Button;
                case '6'
                    b=app.A1Button;
                case '7'
                    b=app.B1Button;
                case 'q'
                    b=app.C2Button;
                case 'w'
                    b=app.D2Button;
                case 'e'
                    b=app.E2Button;
                case 'r'
                    b=app.F2Button;
                case 't'
                    b=app.G2Button;
                case 'y'
                    b=app.A2Button;
                case 'u'
                    b=app.B2Button;
                otherwise
                    b="";
            end
            if b~=""
                % 模拟按钮弹起动画
                set(b, 'BackgroundColor', [1 1 1]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '电子钢琴';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.C1Button = uibutton(app.UIFigure, 'state');
            app.C1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.C1Button.Text = 'C1';
            app.D1Button = uibutton(app.UIFigure, 'state');
            app.D1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.D1Button.Text = 'D1';
            app.E1Button = uibutton(app.UIFigure, 'state');
            app.E1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.E1Button.Text = 'E1';
            app.F1Button = uibutton(app.UIFigure, 'state');
            app.F1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.F1Button.Text = 'F1';
            app.G1Button = uibutton(app.UIFigure, 'state');
            app.G1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.G1Button.Text = 'G1';
            app.A1Button = uibutton(app.UIFigure, 'state');
            app.A1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.A1Button.Text = 'A1';
            app.B1Button = uibutton(app.UIFigure, 'state');
            app.B1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.B1Button.Text = 'B1';
            app.Cb1Button = uibutton(app.UIFigure, 'state');
            app.Cb1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Cb1Button.Text = 'Cb1';
            app.Db1Button = uibutton(app.UIFigure, 'state');
            app.Db1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Db1Button.Text = 'Db1';
            app.Fb1Button = uibutton(app.UIFigure, 'state');
            app.Fb1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Fb1Button.Text = 'Fb1';
            app.Gb1Button = uibutton(app.UIFigure, 'state');
            app.Gb1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Gb1Button.Text = 'Gb1';
            app.Ab1Button = uibutton(app.UIFigure, 'state');
            app.Ab1Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Ab1Button.Text = 'Ab1';
            app.C2Button = uibutton(app.UIFigure, 'state');
            app.C2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.C2Button.Text = 'C2';
            app.D2Button = uibutton(app.UIFigure, 'state');
            app.D2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.D2Button.Text = 'D2';
            app.E2Button = uibutton(app.UIFigure, 'state');
            app.E2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.E2Button.Text = 'E2';
            app.F2Button = uibutton(app.UIFigure, 'state');
            app.F2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.F2Button.Text = 'F2';
            app.G2Button = uibutton(app.UIFigure, 'state');
            app.G2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.G2Button.Text = 'G2';
            app.A2Button = uibutton(app.UIFigure, 'state');
            app.A2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.A2Button.Text = 'A2';
            app.B2Button = uibutton(app.UIFigure, 'state');
            app.B2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.B2Button.Text = 'B2';
            app.Cb2Button = uibutton(app.UIFigure, 'state');
            app.Cb2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Cb2Button.Text = 'Cb2';
            app.Db2Button = uibutton(app.UIFigure, 'state');
            app.Db2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Db2Button.Text = 'Db2';
            app.Fb2Button = uibutton(app.UIFigure, 'state');
            app.Fb2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Fb2Button.Text = 'Fb2';
            app.Gb2Button = uibutton(app.UIFigure, 'state');
            app.Gb2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Gb2Button.Text = 'Gb2';
            app.Ab2Button = uibutton(app.UIFigure, 'state');
            app.Ab2Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Ab2Button.Text = 'Ab2';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'延音踏板'; '(几个八分音符时长)'};
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 16];
            app.Slider.Value = 1;
            app.QULabel = uilabel(app.UIFigure);
            app.QULabel.Text = '1-7 Q-U 弹奏唱名';
            app.Ctrl16Label = uilabel(app.UIFigure);
            app.Ctrl16Label.Text = 'Ctrl + 1-6 切换八度';
            app.AMLabel = uilabel(app.UIFigure);
            app.AMLabel.Text = 'A-M 控制延音踏板';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = piano

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end